classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        AnalizarvectorTab             matlab.ui.container.Tab
        GuardargrficaButton           matlab.ui.control.Button
        MetrosEditField               matlab.ui.control.NumericEditField
        MetrosEditFieldLabel          matlab.ui.control.Label
        PixelesEditField              matlab.ui.control.NumericEditField
        PixelesEditFieldLabel         matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        AbrirarchivoButton            matlab.ui.control.Button
        ActualizargrficaPanel         matlab.ui.container.Panel
        SeleccionarconCTRLLabel       matlab.ui.control.Label
        PuntosactivosListBox          matlab.ui.control.ListBox
        PuntosactivosListBoxLabel     matlab.ui.control.Label
        GraficarButton                matlab.ui.control.Button
        AnimarButton                  matlab.ui.control.Button
        ActualizarframesPanel         matlab.ui.container.Panel
        TocarenlagrficaDropDownLabel  matlab.ui.control.Label
        TocarenlagrficaDropDown       matlab.ui.control.DropDown
        Label_2                       matlab.ui.control.Label
        FrameSlider                   matlab.ui.control.Slider
        FrameSliderLabel              matlab.ui.control.Label
        DesplazarentiempoButton       matlab.ui.control.Button
        SeleccionarButton             matlab.ui.control.Button
        ActualizararchivoPanel        matlab.ui.container.Panel
        FramefinalEditField           matlab.ui.control.NumericEditField
        FramefinalEditFieldLabel      matlab.ui.control.Label
        FrameinicialEditField         matlab.ui.control.NumericEditField
        FrameinicialEditFieldLabel    matlab.ui.control.Label
        DesplazamientoangularButton   matlab.ui.control.Button
        ReemplazararchivoButton_2     matlab.ui.control.Button
        GraficarngulosIngresarframesPanel  matlab.ui.container.Panel
        ReferenciaDropDown            matlab.ui.control.DropDown
        ReferenciaDropDownLabel       matlab.ui.control.Label
        PrincipalDropDown             matlab.ui.control.DropDown
        PrincipalDropDownLabel        matlab.ui.control.Label
        AceleracinangularButton       matlab.ui.control.Button
        VelocidadangularButton        matlab.ui.control.Button
        SinarchivoseleccionadoLabel   matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        CalcularmediasTab             matlab.ui.container.Tab
        DesviacinestndarLabel         matlab.ui.control.Label
        MatrizdecorrelacinTextArea    matlab.ui.control.TextArea
        MatrizdecorrelacinTextAreaLabel  matlab.ui.control.Label
        GuardargrficaButton_2         matlab.ui.control.Button
        PromedioButton                matlab.ui.control.Button
        CoeficienteEditField          matlab.ui.control.NumericEditField
        CoeficienteEditFieldLabel     matlab.ui.control.Label
        Button_10                     matlab.ui.control.Button
        Button_9                      matlab.ui.control.Button
        Button_8                      matlab.ui.control.Button
        Button_7                      matlab.ui.control.Button
        Button_6                      matlab.ui.control.Button
        Button_5                      matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        SinarchivoLabel_6             matlab.ui.control.Label
        SinarchivoLabel_5             matlab.ui.control.Label
        SinarchivoLabel_4             matlab.ui.control.Label
        SinarchivoLabel_2             matlab.ui.control.Label
        SinarchivoLabel               matlab.ui.control.Label
        Vector5Button                 matlab.ui.control.Button
        Vector4Button                 matlab.ui.control.Button
        Vector3Button                 matlab.ui.control.Button
        Vector2Button                 matlab.ui.control.Button
        Vector1Button                 matlab.ui.control.Button
        axis_vector                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fullpath;
        data;
        coordenadas_flag=1;
        vector;
        v_1;
        v_2;
        v_3;
        v_4;
        v_5;
        v0_1=1;
        v0_2=1;
        v0_3=1;
        v0_4=1;
        v0_5=1;
        constante_m=1;
    end
    
    methods (Access = public)
        
        function graficar_landmarks(app, col)
            segmentos_cuerpo=[16,20,18,16,22,16,14,12,11,13,15,21,15,19,17,15,13,11,23,25,27,29,31,27,25,23,24,12,24,26,28,30,32,28,-1,10,9,-1,8,6,5,4,0,1,2,3,7];
                % Obtener puntos activos
                puntos_activos = app.PuntosactivosListBox.Value;
                hold(app.UIAxes, 'on');
                % Verificar qué segmentos deben ser dibujados
                for i = 1:length(segmentos_cuerpo)-1
                    if ismember(segmentos_cuerpo(i), puntos_activos) && ismember(segmentos_cuerpo(i+1), puntos_activos)
                        plot(app.UIAxes, ...
                             [app.data(col,segmentos_cuerpo(i)*3+1), app.data(col,segmentos_cuerpo(i+1)*3+1)], ...
                             [app.data(col,segmentos_cuerpo(i)*3+2), app.data(col,segmentos_cuerpo(i+1)*3+2)], 'k');           
                    end
                end
                hold(app.UIAxes, 'off');
        end
        function draw_all(app)
            pause(0.2);
            hold(app.axis_vector, 'off');
            plot(app.axis_vector, app.v0_5:1:(length(app.v_5)+app.v0_5-1),app.v_5,'y');
            hold(app.axis_vector, 'on');
            plot(app.axis_vector, app.v0_4:1:(length(app.v_4)+app.v0_4-1),app.v_4,'m');
            plot(app.axis_vector, app.v0_3:1:(length(app.v_3)+app.v0_3-1),app.v_3,'g');
            plot(app.axis_vector, app.v0_2:1:(length(app.v_2)+app.v0_2-1),app.v_2,'r');
            plot(app.axis_vector, app.v0_1:1:(length(app.v_1)+app.v0_1-1),app.v_1,'b');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AbrirarchivoButton
        function abrir(app, event)
            % Abrir el cuadro de diálogo para seleccionar el archivo Excel
            [filename, filepath] = uigetfile({'*.xlsx', 'Archivos de Excel (*.xlsx)'; '*.xls', 'Archivos de Excel Antiguos (*.xls)'}, 'Selecciona un archivo Excel');
            
            % Comprobar si el usuario ha cancelado la selección
            if isequal(filename,0) || isequal(filepath,0)
                app.SinarchivoseleccionadoLabel.Text='Selección cancelada. Saliendo del script.';
                return;
            end
            
            % Concatenar el nombre del archivo y la ruta
            excel_file = fullfile(filepath, filename);
            
            % Comprobar si el archivo Excel existe
            if ~exist(excel_file, 'file')
                app.SinarchivoseleccionadoLabel.Text='El archivo especificado no existe.';
            end
            try
                % Forzar la apertura del archivo Excel (esto depende de tu sistema operativo)
                if endsWith(excel_file, '.xlsx')
                    app.data = xlsread(excel_file); % Para archivos .xlsx
                    app.SinarchivoseleccionadoLabel.Text=excel_file;
                    app.ActualizararchivoPanel.Enable="on";
                    app.ActualizarframesPanel.Enable="on";
                    app.ActualizargrficaPanel.Enable="on";
                    app.GraficarngulosIngresarframesPanel.Enable="on";
                    app.FrameSlider.Limits=[1,length(app.data(:,1))];
                    for i=1:33
                        app.data(:,i*3-2)=app.data(:,i*3-2);
                        app.data(:,i*3-1)=-app.data(:,i*3-1);
                        app.data(:,i*3)=0;
                    end
                    graficar_landmarks(app,1);
                    app.fullpath=excel_file;
                elseif endsWith(excel_file, '.xls')
                    app.data = xlsread(excel_file); % Para archivos .xls
                    app.SinarchivoseleccionadoLabel.Text=excel_file;
                    app.ActualizararchivoPanel.Enable="on";
                    app.ActualizarframesPanel.Enable="on";
                    app.ActualizargrficaPanel.Enable="on";
                    app.GraficarngulosIngresarframesPanel.Enable="on";
                    app.FrameSlider.Limits=[1,length(app.data(:,1))];
                    for i=1:33
                        app.data(:,i*3-2)=app.data(:,i*3-2);
                        app.data(:,i*3-1)=app.data(:,i*3-1);
                        app.data(:,i*3)=0;
                    end
                    app.fullpath=excel_file;
                else
                    app.SinarchivoseleccionadoLabel.Text='Archivo no compatible.';
                end
            
            catch ME
                % Manejar cualquier error que ocurra durante la apertura del archivo Excel
                app.SinarchivoseleccionadoLabel.Text=['Error al abrir el archivo Excel: ', ME.message];
            end
        end

        % Button pushed function: AnimarButton
        function animar(app, event)
            for i=1:length(app.data(:,1))
                    graficar_landmarks(app,i);
                    pause(0.1);
                    plot(app.UIAxes, 0,0);
            end
        end

        % Button pushed function: GraficarButton
        function btn_graficar(app, event)
            for i=1:length(app.data(:,1))
                    graficar_landmarks(app,i);
                    pause(0.01);
            end
        end

        % Button pushed function: SeleccionarButton
        function ginput_frame(app, event)
            
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            if (app.coordenadas_flag)
                % Activa hold on para mantener la trama existente
                hold(app.UIAxes, 'on');
                
                % Permite que el usuario haga clic en el gráfico
                [x_click, ~] = ginput(1);
                
                % Encuentra el valor de x más cercano en tus datos
                puntos_x = app.data(:,(app.TocarenlagrficaDropDown.Value+1)*3-2);
                [~, idx] = min(abs(puntos_x - x_click));
                x_closest = puntos_x(idx);
                y_closest = app.data(idx, (app.TocarenlagrficaDropDown.Value+1)*3-1);
                
                % Dibuja un punto rojo en el punto más cercano
                plot(app.UIAxes, x_closest, y_closest, 'ro');
                
                % Desactiva hold on
                hold(app.UIAxes, 'off');
                app.FrameinicialEditField.Value=idx;
            else
                % Activa hold on para mantener la trama existente
                hold(app.UIAxes, 'on');
                
                % Permite que el usuario haga clic en el gráfico
                [x_click, ~] = ginput(1);
                
                % Encuentra el valor de x más cercano en tus datos
                puntos_x = app.data(:,(app.TocarenlagrficaDropDown.Value+1)*3-2);
                [~, idx] = min(abs(puntos_x - x_click));
                x_closest = puntos_x(idx);
                y_closest = app.data(idx, (app.TocarenlagrficaDropDown.Value+1)*3-1);
                
                % Dibuja un punto rojo en el punto más cercano
                plot(app.UIAxes, x_closest, y_closest, 'bo');
                
                % Desactiva hold on
                hold(app.UIAxes, 'off');
                app.FramefinalEditField.Value=idx;
            end
            app.coordenadas_flag=~app.coordenadas_flag;
            app.UIFigure.HandleVisibility = fhv;        % return original state
        end

        % Button pushed function: DesplazarentiempoButton
        function desplazar_frame(app, event)
                    graficar_landmarks(app,app.FrameSlider.Value);
        end

        % Button pushed function: ReemplazararchivoButton_2
        function actualizar_archivo(app, event)
            if app.FrameinicialEditField.Value<app.FramefinalEditField.Value
            % Acortar la matriz data
            data_acortada = app.data(app.FrameinicialEditField.Value:app.FramefinalEditField.Value, :);
            
            % Guardar los nuevos valores en un nuevo archivo Excel
            nombre_archivo_nuevo = app.fullpath;
            writematrix(data_acortada, nombre_archivo_nuevo);
            
            % Si quieres modificar el archivo original
            % Leer los datos del archivo original
            [datos_originales, ~] = xlsread(app.fullpath);
            
            % Modificar los datos según sea necesario
            datos_actualizados = datos_originales(app.FrameinicialEditField.Value:app.FramefinalEditField.Value, :);
            
            % Escribir los datos actualizados en el archivo original
            xlswrite(app.fullpath, datos_actualizados);
            end

        end

        % Button pushed function: DesplazamientoangularButton
        function desplazamiento_angular(app, event)
            % Coordenadas del primer punto
            x1 = app.data(app.FrameinicialEditField.Value:app.FramefinalEditField.Value:app.FrameinicialEditField.Value:app.FramefinalEditField.Value,(app.PrincipalDropDown.Value+1)*3-2);
            y1 = app.data(app.FrameinicialEditField.Value:app.FramefinalEditField.Value:app.FrameinicialEditField.Value:app.FramefinalEditField.Value,(app.PrincipalDropDown.Value+1)*3-1);
            
            % Coordenadas del segundo punto
            
            x2 = app.data(app.FrameinicialEditField.Value:app.FramefinalEditField.Value:app.FrameinicialEditField.Value:app.FramefinalEditField.Value,(app.ReferenciaDropDown.Value+1)*3-2);
            y2 = app.data(app.FrameinicialEditField.Value:app.FramefinalEditField.Value:app.FrameinicialEditField.Value:app.FramefinalEditField.Value,(app.ReferenciaDropDown.Value+1)*3-1);
            
            % Calcular el cambio en las coordenadas y
            delta_y = y2 - y1;
            
            % Calcular el cambio en las coordenadas x
            delta_x = x2 - x1;
            
            % Calcular el ángulo entre los dos puntos
            angulo = atan2(delta_y, delta_x);
            
            % Convertir el ángulo a grados si es necesario
            angulo_grados = rad2deg(angulo);

            % Graficar los ángulos
            plot(app.UIAxes,angulo_grados,1:length(angulo_grados));
            
            app.vector = angulo_grados;
            disp(app.vector);
        end

        % Button pushed function: GuardargrficaButton, 
        % ...and 1 other component
        function guardar_grafica(app, event)
       % Abrir ventana del explorador de archivos
    [nombre_archivo, ruta] = uiputfile('*.txt', 'Guardar vector y de la gráfica como');
    
    % Verificar si el usuario canceló la operación
    if isequal(nombre_archivo, 0) || isequal(ruta, 0)
        disp('Operación cancelada por el usuario.');
    else
        % Combinar el nombre del archivo y la ruta
        ruta_completa = fullfile(ruta, nombre_archivo);
        
        % Abrir el archivo para escritura
        fid = fopen(ruta_completa, 'w');
    
        % Verificar si se pudo abrir el archivo
        if fid == -1
            error('No se pudo abrir el archivo.');
        end
    
        % Escribir el vector en el archivo de texto
        fprintf(fid, '%d\n', app.vector);
    
        % Cerrar el archivo
        fclose(fid);
        
        disp(['Vector guardado en: ', ruta_completa]);
    end

        end

        % Button pushed function: Vector1Button
        function openvector1(app, event)
% Abrir el cuadro de diálogo para seleccionar el archivo de texto
[filename, filepath] = uigetfile({'*.txt', 'Archivos de Texto (*.txt)'; '*.csv', 'Archivos CSV (*.csv)'}, 'Selecciona un archivo de texto');

% Comprobar si el usuario ha cancelado la selección
if isequal(filename,0) || isequal(filepath,0)
    app.SinarchivoLabel.Text='Selección cancelada. Saliendo del script.';
    return;
end

% Concatenar el nombre del archivo y la ruta
txt_file = fullfile(filepath, filename);

% Comprobar si el archivo de texto existe
if ~exist(txt_file, 'file')
    app.SinarchivoLabel.Text='El archivo especificado no existe.';
    return;
end

try
    % Leer el archivo de texto sin especificar delimitador (detecta automáticamente)
    app.v_1 = readmatrix(txt_file); 
    app.SinarchivoLabel.Text=txt_file;

    % Graficar los datos
    hold(app.axis_vector, 'on');
    plot(app.axis_vector, app.v0_1:1:length(app.v_1),app.v_1,'b');

catch ME
    % Manejar cualquier error que ocurra durante la apertura del archivo de texto
    app.SinarchivoLabel.Text=['Error al abrir el archivo de texto: ', ME.message];
end

        end

        % Button pushed function: Vector2Button
        function abrir_v2(app, event)
% Abrir el cuadro de diálogo para seleccionar el archivo de texto
[filename, filepath] = uigetfile({'*.txt', 'Archivos de Texto (*.txt)'; '*.csv', 'Archivos CSV (*.csv)'}, 'Selecciona un archivo de texto');

% Comprobar si el usuario ha cancelado la selección
if isequal(filename,0) || isequal(filepath,0)
    app.SinarchivoLabel_2.Text='Selección cancelada. Saliendo del script.';
    return;
end

% Concatenar el nombre del archivo y la ruta
txt_file = fullfile(filepath, filename);

% Comprobar si el archivo de texto existe
if ~exist(txt_file, 'file')
    app.SinarchivoLabel_2.Text='El archivo especificado no existe.';
    return;
end

try
    % Leer el archivo de texto sin especificar delimitador (detecta automáticamente)
    app.v_2 = readmatrix(txt_file); 
    app.SinarchivoLabel_2.Text=txt_file;

    % Graficar los datos
    hold(app.axis_vector, 'on');
    plot(app.axis_vector, app.v0_2:1:length(app.v_2),app.v_2,'r');

catch ME
    % Manejar cualquier error que ocurra durante la apertura del archivo de texto
    app.SinarchivoLabel_2.Text=['Error al abrir el archivo de texto: ', ME.message];
end

        end

        % Button pushed function: Vector3Button
        function abrir_v3(app, event)
            % Abrir el cuadro de diálogo para seleccionar el archivo de texto
[filename, filepath] = uigetfile({'*.txt', 'Archivos de Texto (*.txt)'; '*.csv', 'Archivos CSV (*.csv)'}, 'Selecciona un archivo de texto');

% Comprobar si el usuario ha cancelado la selección
if isequal(filename,0) || isequal(filepath,0)
    app.SinarchivoLabel_4.Text='Selección cancelada. Saliendo del script.';
    return;
end

% Concatenar el nombre del archivo y la ruta
txt_file = fullfile(filepath, filename);

% Comprobar si el archivo de texto existe
if ~exist(txt_file, 'file')
    app.SinarchivoLabel_4.Text='El archivo especificado no existe.';
    return;
end

try
    % Leer el archivo de texto sin especificar delimitador (detecta automáticamente)
    app.v_3 = readmatrix(txt_file); 
    app.SinarchivoLabel_4.Text=txt_file;

    % Graficar los datos
    hold(app.axis_vector, 'on');
    plot(app.axis_vector, app.v0_3:1:length(app.v_3),app.v_3,'g');

catch ME
    % Manejar cualquier error que ocurra durante la apertura del archivo de texto
    app.SinarchivoLabel_4.Text=['Error al abrir el archivo de texto: ', ME.message];
end

        end

        % Button pushed function: Vector4Button
        function abrir_v4(app, event)
            % Abrir el cuadro de diálogo para seleccionar el archivo de texto
[filename, filepath] = uigetfile({'*.txt', 'Archivos de Texto (*.txt)'; '*.csv', 'Archivos CSV (*.csv)'}, 'Selecciona un archivo de texto');

% Comprobar si el usuario ha cancelado la selección
if isequal(filename,0) || isequal(filepath,0)
    app.SinarchivoLabel_5.Text='Selección cancelada. Saliendo del script.';
    return;
end

% Concatenar el nombre del archivo y la ruta
txt_file = fullfile(filepath, filename);

% Comprobar si el archivo de texto existe
if ~exist(txt_file, 'file')
    app.SinarchivoLabel_5.Text='El archivo especificado no existe.';
    return;
end

try
    % Leer el archivo de texto sin especificar delimitador (detecta automáticamente)
    app.v_4 = readmatrix(txt_file); 
    app.SinarchivoLabel_5.Text=txt_file;

    % Graficar los datos
    hold(app.axis_vector, 'on');
    plot(app.axis_vector, app.v0_4:1:length(app.v_4),app.v_4,'m');

catch ME
    % Manejar cualquier error que ocurra durante la apertura del archivo de texto
    app.SinarchivoLabel_5.Text=['Error al abrir el archivo de texto: ', ME.message];
end

        end

        % Button pushed function: Vector5Button
        function abrir_v5(app, event)
            % Abrir el cuadro de diálogo para seleccionar el archivo de texto
[filename, filepath] = uigetfile({'*.txt', 'Archivos de Texto (*.txt)'; '*.csv', 'Archivos CSV (*.csv)'}, 'Selecciona un archivo de texto');

% Comprobar si el usuario ha cancelado la selección
if isequal(filename,0) || isequal(filepath,0)
    app.SinarchivoLabel_6.Text='Selección cancelada. Saliendo del script.';
    return;
end

% Concatenar el nombre del archivo y la ruta
txt_file = fullfile(filepath, filename);

% Comprobar si el archivo de texto existe
if ~exist(txt_file, 'file')
    app.SinarchivoLabel_6.Text='El archivo especificado no existe.';
    return;
end

try
    % Leer el archivo de texto sin especificar delimitador (detecta automáticamente)
    app.v_5 = readmatrix(txt_file); 
    app.SinarchivoLabel_6.Text=txt_file;

    % Graficar los datos
    hold(app.axis_vector, 'on');
    plot(app.axis_vector, app.v0_5:1:length(app.v_5),app.v_5,'y');

catch ME
    % Manejar cualquier error que ocurra durante la apertura del archivo de texto
    app.SinarchivoLabel_6.Text=['Error al abrir el archivo de texto: ', ME.message];
end

        end

        % Button pushed function: Button_6
        function der_1(app, event)
            app.v0_1=app.v0_1+app.constante_m;
            draw_all(app);
        end

        % Button pushed function: Button_7
        function der_2(app, event)
            app.v0_2=app.v0_2+app.constante_m;
            draw_all(app);
        end

        % Button pushed function: Button_8
        function der_3(app, event)
            app.v0_3=app.v0_3+app.constante_m;
            draw_all(app);
        end

        % Button pushed function: Button_9
        function der_4(app, event)
            app.v0_4=app.v0_4+app.constante_m;
            draw_all(app);
        end

        % Button pushed function: Button_10
        function der_5(app, event)
            app.v0_5=app.v0_5+app.constante_m;
            draw_all(app);
        end

        % Button pushed function: Button
        function izq_1(app, event)
            app.v0_1=app.v0_1-app.constante_m;
            draw_all(app);
        end

        % Button pushed function: Button_2
        function izq_2(app, event)
            app.v0_2=app.v0_2-app.constante_m;
            draw_all(app);
        end

        % Button pushed function: Button_3
        function izq_3(app, event)
            app.v0_3=app.v0_3-app.constante_m;
            draw_all(app);
        end

        % Button pushed function: Button_4
        function izq_4(app, event)
            app.v0_4=app.v0_4-app.constante_m;
            draw_all(app);
        end

        % Button pushed function: Button_5
        function izq_5(app, event)
            app.v0_5=app.v0_5-app.constante_m;
            draw_all(app);
        end

        % Value changed function: CoeficienteEditField
        function change_constante(app, event)
            value = app.CoeficienteEditField.Value;
            app.constante_m=value;
        end

        % Button pushed function: PromedioButton
        function calcular_promedio(app, event)
% Encuentra la longitud máxima de los vectores considerando sus posiciones iniciales
max_length = max([app.v0_1 + length(app.v_1) - 1, ...
                  app.v0_2 + length(app.v_2) - 1, ...
                  app.v0_3 + length(app.v_3) - 1, ...
                  app.v0_4 + length(app.v_4) - 1, ...
                  app.v0_5 + length(app.v_5) - 1]);

% Encuentra la longitud mínima de los vectores considerando sus posiciones iniciales
min_length = min([app.v0_1, app.v0_2, app.v0_3, app.v0_4, app.v0_5]);

% Crear el vector de x común
x_common = min_length:max_length;

% Inicializa una matriz con NaN para almacenar los vectores con la misma longitud
all_vectors = nan(length(x_common), 5);

% Copia los valores de cada vector a la matriz en las posiciones adecuadas
all_vectors(app.v0_1 - min_length + 1 : app.v0_1 - min_length + length(app.v_1), 1) = app.v_1;
all_vectors(app.v0_2 - min_length + 1 : app.v0_2 - min_length + length(app.v_2), 2) = app.v_2;
all_vectors(app.v0_3 - min_length + 1 : app.v0_3 - min_length + length(app.v_3), 3) = app.v_3;
all_vectors(app.v0_4 - min_length + 1 : app.v0_4 - min_length + length(app.v_4), 4) = app.v_4;
all_vectors(app.v0_5 - min_length + 1 : app.v0_5 - min_length + length(app.v_5), 5) = app.v_5;

% Calcular el promedio ignorando los NaN
app.vector = nanmean(all_vectors, 2);

% Calcular la desviación estándar ignorando los NaN
v_std = nanstd(all_vectors, 0, 2);

% Calcular el coeficiente de correlación
correlation_matrix = corrcoef(all_vectors, 'Rows', 'pairwise');

% Graficar el vector promedio con desviación estándar
figure;
hold on;
plot(x_common, app.vector, 'DisplayName', 'Vector Promedio', 'LineWidth', 2, 'Color', 'k');
fill([x_common, fliplr(x_common)], [app.vector - v_std; flipud(app.vector + v_std)], ...
    'k', 'FaceAlpha', 0.2, 'EdgeColor', 'none', 'DisplayName', 'Desviación Estándar');
legend;
hold off;

% Mostrar la matriz de correlación
disp('Matriz de Correlación:');
disp(correlation_matrix);

% Calcular la desviación estándar promedio
std_avg = mean(v_std, 'omitnan');

% Mostrar la desviación estándar promedio en el Label
app.DesviacinestndarLabel.Text = ['Desviación estándar promedio: ', num2str(std_avg)];

correlation_str = '';
for i = 1:size(correlation_matrix, 1)
    for j = 1:size(correlation_matrix, 2)
        correlation_str = [correlation_str, sprintf('\t%.2f', correlation_matrix(i, j))];
    end
    correlation_str = [correlation_str, sprintf('\n')];
end
app.MatrizdecorrelacinTextArea.Value = correlation_str;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnalizarvectorTab = uitab(app.TabGroup);
            app.AnalizarvectorTab.Title = 'Analizar vector';
            app.UIAxes = uiaxes(app.AnalizarvectorTab);
            title(app.UIAxes, 'Gráfica de los puntos')
            xlabel(app.UIAxes, 'Longitud (m)')
            ylabel(app.UIAxes, 'Altura (m)')
            zlabel(app.UIAxes, 'Z')
            app.SinarchivoseleccionadoLabel = uilabel(app.AnalizarvectorTab);
            app.SinarchivoseleccionadoLabel.Text = 'Sin archivo seleccionado';
            app.GraficarngulosIngresarframesPanel = uipanel(app.AnalizarvectorTab);
            app.GraficarngulosIngresarframesPanel.Enable = 'off';
            app.GraficarngulosIngresarframesPanel.Title = 'Graficar ángulos - (Ingresar frames)';
            app.VelocidadangularButton = uibutton(app.GraficarngulosIngresarframesPanel, 'push');
            app.VelocidadangularButton.Enable = 'off';
            app.VelocidadangularButton.Visible = 'off';
            app.VelocidadangularButton.Text = 'Velocidad angular';
            app.AceleracinangularButton = uibutton(app.GraficarngulosIngresarframesPanel, 'push');
            app.AceleracinangularButton.Enable = 'off';
            app.AceleracinangularButton.Visible = 'off';
            app.AceleracinangularButton.Text = 'Aceleración angular';
            app.PrincipalDropDownLabel = uilabel(app.GraficarngulosIngresarframesPanel);
            app.PrincipalDropDownLabel.Text = 'Principal';
            app.PrincipalDropDown = uidropdown(app.GraficarngulosIngresarframesPanel);
            app.PrincipalDropDown.Items = {'Nariz', 'Ojo izq int', 'Ojo izq med', 'Ojo izq ext', 'Ojo der int', 'Ojo der med', 'Ojo der ext', 'Oreja izq', 'Oreja der', 'Boca izq', 'Boca der', 'Hombro izq', 'Hombro der', 'Codo izq', 'Codo der', 'Muñeca izq', 'Muñeca der', 'Meñique izq', 'Meñique der', 'Índice izq', 'Índice der', 'Pulgar izq', 'Pulgar der', 'Cadera izq', 'Cadera der', 'Rodilla izq', 'Rodilla der', 'Tobillo izq', 'Tobillo der', 'Talon izq', 'Talon der', 'Pie izq', 'Pie der'};
            app.PrincipalDropDown.ItemsData = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32];
            app.PrincipalDropDown.Placeholder = 'Sin selección';
            app.PrincipalDropDown.Value = 0;
            app.ReferenciaDropDownLabel = uilabel(app.GraficarngulosIngresarframesPanel);
            app.ReferenciaDropDownLabel.Text = 'Referencia';
            app.ReferenciaDropDown = uidropdown(app.GraficarngulosIngresarframesPanel);
            app.ReferenciaDropDown.Items = {'Nariz', 'Ojo izq int', 'Ojo izq med', 'Ojo izq ext', 'Ojo der int', 'Ojo der med', 'Ojo der ext', 'Oreja izq', 'Oreja der', 'Boca izq', 'Boca der', 'Hombro izq', 'Hombro der', 'Codo izq', 'Codo der', 'Muñeca izq', 'Muñeca der', 'Meñique izq', 'Meñique der', 'Índice izq', 'Índice der', 'Pulgar izq', 'Pulgar der', 'Cadera izq', 'Cadera der', 'Rodilla izq', 'Rodilla der', 'Tobillo izq', 'Tobillo der', 'Talon izq', 'Talon der', 'Pie izq', 'Pie der'};
            app.ReferenciaDropDown.ItemsData = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32];
            app.ReferenciaDropDown.Placeholder = 'Sin selección';
            app.ReferenciaDropDown.Value = 0;
            app.ActualizararchivoPanel = uipanel(app.AnalizarvectorTab);
            app.ActualizararchivoPanel.Enable = 'off';
            app.ActualizararchivoPanel.Title = 'Actualizar archivo';
            app.ReemplazararchivoButton_2 = uibutton(app.ActualizararchivoPanel, 'push');
            app.ReemplazararchivoButton_2.ButtonPushedFcn = createCallbackFcn(app, @actualizar_archivo, true);
            app.ReemplazararchivoButton_2.Text = 'Reemplazar archivo';
            app.DesplazamientoangularButton = uibutton(app.ActualizararchivoPanel, 'push');
            app.DesplazamientoangularButton.ButtonPushedFcn = createCallbackFcn(app, @desplazamiento_angular, true);
            app.DesplazamientoangularButton.Text = 'Desplazamiento angular';
            app.FrameinicialEditFieldLabel = uilabel(app.ActualizararchivoPanel);
            app.FrameinicialEditFieldLabel.Text = 'Frame inicial';
            app.FrameinicialEditField = uieditfield(app.ActualizararchivoPanel, 'numeric');
            app.FramefinalEditFieldLabel = uilabel(app.ActualizararchivoPanel);
            app.FramefinalEditFieldLabel.Text = 'Frame final';
            app.FramefinalEditField = uieditfield(app.ActualizararchivoPanel, 'numeric');
            app.ActualizarframesPanel = uipanel(app.AnalizarvectorTab);
            app.ActualizarframesPanel.Enable = 'off';
            app.ActualizarframesPanel.Title = 'Actualizar frames';
            app.SeleccionarButton = uibutton(app.ActualizarframesPanel, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @ginput_frame, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.DesplazarentiempoButton = uibutton(app.ActualizarframesPanel, 'push');
            app.DesplazarentiempoButton.ButtonPushedFcn = createCallbackFcn(app, @desplazar_frame, true);
            app.DesplazarentiempoButton.Text = 'Desplazar en tiempo';
            app.FrameSliderLabel = uilabel(app.ActualizarframesPanel);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.ActualizarframesPanel);
            app.Label_2 = uilabel(app.ActualizarframesPanel);
            app.Label_2.Text = '';
            app.TocarenlagrficaDropDown = uidropdown(app.ActualizarframesPanel);
            app.TocarenlagrficaDropDown.Items = {'Nariz', 'Ojo izq int', 'Ojo izq med', 'Ojo izq ext', 'Ojo der int', 'Ojo der med', 'Ojo der ext', 'Oreja izq', 'Oreja der', 'Boca izq', 'Boca der', 'Hombro izq', 'Hombro der', 'Codo izq', 'Codo der', 'Muñeca izq', 'Muñeca der', 'Meñique izq', 'Meñique der', 'Índice izq', 'Índice der', 'Pulgar izq', 'Pulgar der', 'Cadera izq', 'Cadera der', 'Rodilla izq', 'Rodilla der', 'Tobillo izq', 'Tobillo der', 'Talon izq', 'Talon der', 'Pie izq', 'Pie der'};
            app.TocarenlagrficaDropDown.ItemsData = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32];
            app.TocarenlagrficaDropDown.Placeholder = 'Sin selección';
            app.TocarenlagrficaDropDown.Value = 0;
            app.TocarenlagrficaDropDownLabel = uilabel(app.ActualizarframesPanel);
            app.TocarenlagrficaDropDownLabel.Text = 'Tocar en la gráfica';
            app.ActualizargrficaPanel = uipanel(app.AnalizarvectorTab);
            app.ActualizargrficaPanel.Enable = 'off';
            app.ActualizargrficaPanel.Title = 'Actualizar gráfica';
            app.AnimarButton = uibutton(app.ActualizargrficaPanel, 'push');
            app.AnimarButton.ButtonPushedFcn = createCallbackFcn(app, @animar, true);
            app.AnimarButton.Text = 'Animar';
            app.GraficarButton = uibutton(app.ActualizargrficaPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @btn_graficar, true);
            app.GraficarButton.Text = 'Graficar';
            app.PuntosactivosListBoxLabel = uilabel(app.ActualizargrficaPanel);
            app.PuntosactivosListBoxLabel.Text = 'Puntos activos';
            app.PuntosactivosListBox = uilistbox(app.ActualizargrficaPanel);
            app.PuntosactivosListBox.Items = {'Nariz', 'Ojo izq int', 'Ojo izq med', 'Ojo izq ext', 'Ojo der int', 'Ojo der med', 'Ojo der ext', 'Oreja izq', 'Oreja der', 'Boca izq', 'Boca der', 'Hombro izq', 'Hombro der', 'Codo izq', 'Codo der', 'Muñeca izq', 'Muñeca der', 'Meñique izq', 'Meñique der', 'Índice izq', 'Índice der', 'Pulgar izq', 'Pulgar der', 'Cadera izq', 'Cadera der', 'Rodilla izq', 'Rodilla der', 'Tobillo izq', 'Tobido der', 'Talon izq', 'Talon der', 'Pie izq', 'Pie der'};
            app.PuntosactivosListBox.ItemsData = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32];
            app.PuntosactivosListBox.Multiselect = 'on';
            app.PuntosactivosListBox.Value = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32];
            app.SeleccionarconCTRLLabel = uilabel(app.ActualizargrficaPanel);
            app.SeleccionarconCTRLLabel.Text = {'Seleccionar con '; 'CTRL'};
            app.AbrirarchivoButton = uibutton(app.AnalizarvectorTab, 'push');
            app.AbrirarchivoButton.ButtonPushedFcn = createCallbackFcn(app, @abrir, true);
            app.AbrirarchivoButton.Text = 'Abrir archivo';
            app.Label = uilabel(app.AnalizarvectorTab);
            app.Label.Text = ':';
            app.PixelesEditFieldLabel = uilabel(app.AnalizarvectorTab);
            app.PixelesEditFieldLabel.Text = 'Pixeles';
            app.PixelesEditField = uieditfield(app.AnalizarvectorTab, 'numeric');
            app.PixelesEditField.Limits = [1 Inf];
            app.PixelesEditField.Value = 1;
            app.MetrosEditFieldLabel = uilabel(app.AnalizarvectorTab);
            app.MetrosEditFieldLabel.Text = 'Metros';
            app.MetrosEditField = uieditfield(app.AnalizarvectorTab, 'numeric');
            app.MetrosEditField.Limits = [1 Inf];
            app.MetrosEditField.Value = 1;
            app.GuardargrficaButton = uibutton(app.AnalizarvectorTab, 'push');
            app.GuardargrficaButton.ButtonPushedFcn = createCallbackFcn(app, @guardar_grafica, true);
            app.GuardargrficaButton.Text = 'Guardar gráfica';
            app.CalcularmediasTab = uitab(app.TabGroup);
            app.CalcularmediasTab.Title = 'Calcular medias';
            app.axis_vector = uiaxes(app.CalcularmediasTab);
            title(app.axis_vector, 'Title')
            xlabel(app.axis_vector, 'X')
            ylabel(app.axis_vector, 'Y')
            zlabel(app.axis_vector, 'Z')
            app.Vector1Button = uibutton(app.CalcularmediasTab, 'push');
            app.Vector1Button.ButtonPushedFcn = createCallbackFcn(app, @openvector1, true);
            app.Vector1Button.Text = 'Vector 1';
            app.Vector2Button = uibutton(app.CalcularmediasTab, 'push');
            app.Vector2Button.ButtonPushedFcn = createCallbackFcn(app, @abrir_v2, true);
            app.Vector2Button.Text = 'Vector 2';
            app.Vector3Button = uibutton(app.CalcularmediasTab, 'push');
            app.Vector3Button.ButtonPushedFcn = createCallbackFcn(app, @abrir_v3, true);
            app.Vector3Button.Text = 'Vector 3';
            app.Vector4Button = uibutton(app.CalcularmediasTab, 'push');
            app.Vector4Button.ButtonPushedFcn = createCallbackFcn(app, @abrir_v4, true);
            app.Vector4Button.Text = 'Vector 4';
            app.Vector5Button = uibutton(app.CalcularmediasTab, 'push');
            app.Vector5Button.ButtonPushedFcn = createCallbackFcn(app, @abrir_v5, true);
            app.Vector5Button.Text = 'Vector 5';
            app.SinarchivoLabel = uilabel(app.CalcularmediasTab);
            app.SinarchivoLabel.Text = 'Sin archivo';
            app.SinarchivoLabel_2 = uilabel(app.CalcularmediasTab);
            app.SinarchivoLabel_2.Text = 'Sin archivo';
            app.SinarchivoLabel_4 = uilabel(app.CalcularmediasTab);
            app.SinarchivoLabel_4.Text = 'Sin archivo';
            app.SinarchivoLabel_5 = uilabel(app.CalcularmediasTab);
            app.SinarchivoLabel_5.Text = 'Sin archivo';
            app.SinarchivoLabel_6 = uilabel(app.CalcularmediasTab);
            app.SinarchivoLabel_6.Text = 'Sin archivo';
            app.Button = uibutton(app.CalcularmediasTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @izq_1, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.CalcularmediasTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @izq_2, true);
            app.Button_2.Text = '<';
            app.Button_3 = uibutton(app.CalcularmediasTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @izq_3, true);
            app.Button_3.Text = '<';
            app.Button_4 = uibutton(app.CalcularmediasTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @izq_4, true);
            app.Button_4.Text = '<';
            app.Button_5 = uibutton(app.CalcularmediasTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @izq_5, true);
            app.Button_5.Text = '<';
            app.Button_6 = uibutton(app.CalcularmediasTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @der_1, true);
            app.Button_6.Text = '>';
            app.Button_7 = uibutton(app.CalcularmediasTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @der_2, true);
            app.Button_7.Text = '>';
            app.Button_8 = uibutton(app.CalcularmediasTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @der_3, true);
            app.Button_8.Text = '>';
            app.Button_9 = uibutton(app.CalcularmediasTab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @der_4, true);
            app.Button_9.Text = '>';
            app.Button_10 = uibutton(app.CalcularmediasTab, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @der_5, true);
            app.Button_10.Text = '>';
            app.CoeficienteEditFieldLabel = uilabel(app.CalcularmediasTab);
            app.CoeficienteEditFieldLabel.Text = 'Coeficiente';
            app.CoeficienteEditField = uieditfield(app.CalcularmediasTab, 'numeric');
            app.CoeficienteEditField.Limits = [1 Inf];
            app.CoeficienteEditField.ValueChangedFcn = createCallbackFcn(app, @change_constante, true);
            app.CoeficienteEditField.Value = 1;
            app.PromedioButton = uibutton(app.CalcularmediasTab, 'push');
            app.PromedioButton.ButtonPushedFcn = createCallbackFcn(app, @calcular_promedio, true);
            app.PromedioButton.Text = 'Promedio';
            app.GuardargrficaButton_2 = uibutton(app.CalcularmediasTab, 'push');
            app.GuardargrficaButton_2.ButtonPushedFcn = createCallbackFcn(app, @guardar_grafica, true);
            app.GuardargrficaButton_2.Text = 'Guardar gráfica';
            app.MatrizdecorrelacinTextAreaLabel = uilabel(app.CalcularmediasTab);
            app.MatrizdecorrelacinTextAreaLabel.Enable = 'off';
            app.MatrizdecorrelacinTextAreaLabel.Text = 'Matriz de correlación';
            app.MatrizdecorrelacinTextArea = uitextarea(app.CalcularmediasTab);
            app.MatrizdecorrelacinTextArea.Editable = 'off';
            app.MatrizdecorrelacinTextArea.Enable = 'off';
            app.DesviacinestndarLabel = uilabel(app.CalcularmediasTab);
            app.DesviacinestndarLabel.Text = 'Desviación estándar: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end